classdef agentapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        OutputText  matlab.ui.control.TextArea
        Prompt      matlab.ui.control.TextArea
        Image       matlab.ui.control.Image
        GoButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
           prompt = ['You are an AI agent that can perform tasks by using available tools.', ...
                'create a new, blank file named out.txt', ...
                'retreive the list of service request files from the folder srs.  read each file.  If the file is not in english, translate it to english.', ...
                'for each file, suggest a list of 2-3 tags to categorize the request, an assessment of where it is in the workflow,', ...
                'and an issue type.  Then append the list of tags to out.txt in the form of <srfilebane> : <deploment step> : <issue type> : <tag1>, <tag2>...', ...
                'where deployment step represents the step in the deployment process the customer experienced the issue. ', ...
                'the valid deployment steps are in the file deploymentsteps.txt in the agentdata folder ', ...
                'Valid issue types are: request, howto, inquiry, issue. ', ...
                'The valid tags are in tags.txt in the agentdata folder. ', ...
                'When you are done, terminate the conversation by using the "terminate" tool and I will provide the results to the user.'];
            app.Prompt.Value = prompt;
            app.OutputText.Value = "";
            drawnow
            buildyourownagentv2(prompt, app.OutputText);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'agent.png');
            app.Prompt = uitextarea(app.UIFigure);
            app.Prompt.Editable = 'off';
            app.OutputText = uitextarea(app.UIFigure);
            app.OutputText.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agentapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end